classdef PairwiseCompare < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImageAxes                  matlab.ui.control.UIAxes
        LoadButton                 matlab.ui.control.Button
        ImageAxes_2                matlab.ui.control.UIAxes
        LeftArrowButton            matlab.ui.control.Button
        RightArrowButton           matlab.ui.control.Button
        CurrentPairEditFieldLabel  matlab.ui.control.Label
        CurrentPairEditField       matlab.ui.control.EditField
    end

    
    properties (Access = private)
        inDir % input Directory
        inFilesList %List of all image Files
        CompareList %List of all Images to Compare
        CurIndex % Keeps track of which one we're comparing
        RankingMtx %matrix indicating the rater's
        AllCroppedImages %Stores all cropped images for display
        ReadyNext %Flag for when next image pair is ready for selection
        ImagesLoaded %Flag for when images are loaded
    end
    
    methods (Access = private)
        function loadNextImPair(app)
        if (app.CurIndex <= size(app.CompareList,1))
            N = size(app.CompareList,1);
            app.CurrentPairEditField.Value=[num2str(app.CurIndex) ' of ' num2str(N)];
            indx1 = app.CompareList(app.CurIndex,1);
            indx2 = app.CompareList(app.CurIndex,2);
            im1 = app.AllCroppedImages{indx1};
            im2 = app.AllCroppedImages{indx2};
            imLim =max(size(im1),size(im2));
            imshow(im1,'Parent',app.ImageAxes);
            ylim(app.ImageAxes,[0 imLim(1)]);
            xlim(app.ImageAxes,[0 imLim(2)]);

            imshow(im2,'Parent',app.ImageAxes_2);
            ylim(app.ImageAxes_2,[0 imLim(1)]);
            xlim(app.ImageAxes_2,[0 imLim(2)]);

            app.ReadyNext = 1;
        else
            RankingMtx = app.RankingMtx;
            inFilesList = app.inFilesList;
            cla(app.ImageAxes)
            cla(app.ImageAxes_2)

            save(fullfile(app.inDir,'GraderRanking.mat'),"RankingMtx","inFilesList");
            app.CurrentPairEditField.Value='Ranking Saved!';
            app.CurIndex=0;
            app.ReadyNext = 0;
        end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.ImageAxes_2.Visible = 'off';
            app.ImageAxes_2.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            app.ReadyNext = 0;
            app.CurIndex = 0;
            app.ImagesLoaded = 0;
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
        if(~app.ImagesLoaded)
            % Display uigetfile dialog
            [p] = uigetdir();
            drawnow;
            figure(app.UIFigure)
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                app.inDir = [p];
                app.CurrentPairEditField.Value='Loading Images';
                app.LoadButton.Text ='Loading Images...';
                cla(app.ImageAxes)
                cla(app.ImageAxes_2)
                drawnow;
                %load up all images
                app.inFilesList=dir(fullfile(app.inDir,'*split*.tif'));
                N = length(app.inFilesList);
                app.AllCroppedImages = cell(1,N);
                
                for i = 1:N
                    imIn = imread(fullfile(app.inDir,app.inFilesList(i).name));
                    im =  imIn(:,:,1);
                    if(size(imIn,3)==2)
                      imMask = imIn(:,:,2);
                      im(~imMask)=0;
                    end
                    app.AllCroppedImages{i} = cropWhiteSpace(im);
                end
                
                app.CompareList=[];
                N = length(app.inFilesList);
                app.RankingMtx = zeros(N,N);
                for i = 1:N
                    for j = i+1:N
                        app.CompareList = [app.CompareList;i j];
                    end
                end
                randIndx = randperm(size(app.CompareList,1));
                app.CompareList = app.CompareList(randIndx,:);
                app.LoadButton.Text ='Start';
                app.ImagesLoaded = 1;
                app.CurrentPairEditField.Value='Images Loaded';

            end
        else
                app.CurIndex=1;
                app.ReadyNext = 0;
                loadNextImPair(app)
                app.LoadButton.Text ='Load New Dataset';
                app.ImagesLoaded = 0;
        end

        end

        % Button pushed function: LeftArrowButton
        function LeftArrowButtonPushed(app, event)
            if((app.CurIndex > 0) && (app.ReadyNext == 1))
                indx1 = app.CompareList(app.CurIndex,1);
                indx2 = app.CompareList(app.CurIndex,2);
                app.RankingMtx(indx1,indx2) = 1;
                app.CurIndex=app.CurIndex+1;
                app.ReadyNext = 0;
                loadNextImPair(app)
            end
        end

        % Button pushed function: RightArrowButton
        function RightArrowButtonPushed(app, event)
            if((app.CurIndex > 0) && (app.ReadyNext == 1))
                indx1 = app.CompareList(app.CurIndex,1);
                indx2 = app.CompareList(app.CurIndex,2);
                app.RankingMtx(indx1,indx2) = 2;
                app.CurIndex=app.CurIndex+1;
                app.ReadyNext = 0;
                loadNextImPair(app)
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            
            if((app.CurIndex > 0) && (app.ReadyNext == 1))
                if(strcmp(key,'rightarrow') || strcmp(key,'leftarrow'))
                    
                    indx1 = app.CompareList(app.CurIndex,1);
                    indx2 = app.CompareList(app.CurIndex,2);
                    
                    if(strcmp(key,'leftarrow'))
                        app.RankingMtx(indx1,indx2) = 1;
                    elseif(strcmp(key,'rightarrow'))
                        app.RankingMtx(indx1,indx2) = 2;
                    end
                    app.CurIndex=app.CurIndex+1;
                    app.ReadyNext = 0;
                    loadNextImPair(app);
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Pairwise Compare';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Dataset';
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.LeftArrowButton = uibutton(app.UIFigure, 'push');
            app.LeftArrowButton.ButtonPushedFcn = createCallbackFcn(app, @LeftArrowButtonPushed, true);
            app.LeftArrowButton.Text = 'Left Arrow';
            app.RightArrowButton = uibutton(app.UIFigure, 'push');
            app.RightArrowButton.ButtonPushedFcn = createCallbackFcn(app, @RightArrowButtonPushed, true);
            app.RightArrowButton.Text = 'Right Arrow';
            app.CurrentPairEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentPairEditFieldLabel.Text = 'Current Pair';
            app.CurrentPairEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentPairEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PairwiseCompare

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end